classdef AbbasCentral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel2                matlab.ui.container.Panel
        Panel                 matlab.ui.container.Panel
        Slider                matlab.ui.control.Slider
        SliderLabel           matlab.ui.control.Label
        NeuronDropDown        matlab.ui.control.DropDown
        NeuronDropDownLabel   matlab.ui.control.Label
        RasterButton          matlab.ui.control.Button
        SessionDropDown       matlab.ui.control.DropDown
        SessionDropDownLabel  matlab.ui.control.Label
        SubjectDropDown       matlab.ui.control.DropDown
        SubjectDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        sessions % all sessions in the current dataset
        currentSession % index of currently selected subject sessions
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.sessions = evalin('base', 'vipSessions');
            s = evalin('base', 'vipMetadata.subjects');
            app.SubjectDropDown.Items = s;

        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)

            value = app.SubjectDropDown.Value;
            subSessions = arrayfun(@(x) strcmp(x.info.name, value), app.sessions);
            [x{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
            sessionNames = x{2}(subSessions);
            app.SessionDropDown.Items = sessionNames;
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            [X{1:2}] = arrayfun(@(x) fileparts(x.info.path), app.sessions, 'uni', 0);
            whichSession = arrayfun(@(x) strcmp(x, value), X{2});
            app.currentSession = app.sessions(whichSession);
            neuronString = arrayfun(@num2str, 1:numel(app.currentSession.spikes), 'uni', 0);
            app.NeuronDropDown.Items = neuronString;
            delete(app.Panel2.Children)
            app.currentSession.plot_digraph([], app.Panel2);

        end

        % Value changed function: NeuronDropDown
        function NeuronDropDownValueChanged(app, event)
            value = str2double(app.NeuronDropDown.Value);
            delete(app.Panel.Children)
            app.currentSession.raster('Correct Choice', [-2 2], value, app.Panel)
        end

        % Button down function: Panel2
        function Panel2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.RasterButton = uibutton(app.UIFigure, 'push');
            app.RasterButton.Text = 'Raster';
            app.NeuronDropDownLabel = uilabel(app.UIFigure);
            app.NeuronDropDownLabel.Text = 'Neuron';
            app.NeuronDropDown = uidropdown(app.UIFigure);
            app.NeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuronDropDownValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Panel2.ButtonDownFcn = createCallbackFcn(app, @Panel2ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbbasCentral

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end